classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VantageQSPModellingVQMToolsUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        SelectprojectfileButton         matlab.ui.control.Button
        editfield_projectfile           matlab.ui.control.EditField
        table_parameters                matlab.ui.control.Table
        table_species                   matlab.ui.control.Table
        ParametersLabel                 matlab.ui.control.Label
        SpeciesLabel                    matlab.ui.control.Label
        sbprojxmlfileLabel              matlab.ui.control.Label
        cb_replacedashboards            matlab.ui.control.CheckBox
        ProjectnameEditFieldLabel       matlab.ui.control.Label
        editfield_projectname           matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
        ParameterdashboardTab           matlab.ui.container.Tab
        table_pdashboard                matlab.ui.control.Table
        button_pdashboard_list          matlab.ui.control.Button
        button_pdashboard_save          matlab.ui.control.Button
        DashboardnameEditFieldLabel     matlab.ui.control.Label
        editfield_pdashboard_savename   matlab.ui.control.EditField
        button_pdashboard_load          matlab.ui.control.Button
        editfield_pdashboard_parameterfile  matlab.ui.control.EditField
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        radio_pdashboard_frommodel      matlab.ui.control.RadioButton
        radio_pdashboard_fromdashboard  matlab.ui.control.RadioButton
        iDashboardwillbesavedinCVQMtoolsfilesLabel_2  matlab.ui.control.Label
        checkbox_pdashboard_isratio     matlab.ui.control.CheckBox
        csvLabel                        matlab.ui.control.Label
        StartingwithEditFieldLabel      matlab.ui.control.Label
        editfield_pdashboard_startpattern  matlab.ui.control.EditField
        ToggleselectionButton           matlab.ui.control.Button
        ResetselectionButton            matlab.ui.control.Button
        csvLabel_4                      matlab.ui.control.Label
        iEditableLabel_2                matlab.ui.control.Label
        SpeciesdashboardTab             matlab.ui.container.Tab
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        LoadfrommodelButton             matlab.ui.control.RadioButton
        LoadfromfileButton              matlab.ui.control.RadioButton
        button_sdashboard_list          matlab.ui.control.Button
        table_sdashboard                matlab.ui.control.Table
        button_sdashboard_save          matlab.ui.control.Button
        DashboardnameEditFieldLabel_2   matlab.ui.control.Label
        editfield_sdashboard_savename   matlab.ui.control.EditField
        button_sdashboard_load          matlab.ui.control.Button
        editfield_sdashboard_speciesfile  matlab.ui.control.EditField
        iDashboardwillbesavedinCVQMtoolsfilesLabel  matlab.ui.control.Label
        csvLabel_2                      matlab.ui.control.Label
        iEditableLabel                  matlab.ui.control.Label
        LocalSenstivityAnalysisTab      matlab.ui.container.Tab
        RunSensitivityanalysisButton    matlab.ui.control.Button
        plot_lsa                        matlab.ui.control.UIAxes
        editfield_lsa_parameterfile     matlab.ui.control.EditField
        SelectParameterdashboardButton  matlab.ui.control.Button
        editfield_lsa_objhandle         matlab.ui.control.EditField
        editfield_lsa_figname           matlab.ui.control.EditField
        SaveImageButton                 matlab.ui.control.Button
        ObjectivefunctionhandleLabel    matlab.ui.control.Label
        iobjectivefunctionfileshouldbeinCVQMtoolsfunctionsLabel  matlab.ui.control.Label
        ifigurewillbesavedinCVQMtoolsfiguresLabel  matlab.ui.control.Label
        pngLabel                        matlab.ui.control.Label
        Maximumof20parametersaredisplayedLabel  matlab.ui.control.Label
        iLabel_5                        matlab.ui.control.Label
        ParameterEstimationTab          matlab.ui.container.Tab
        editfield_pestimation_pdashboard  matlab.ui.control.EditField
        ObjectivefunctionEditFieldLabel  matlab.ui.control.Label
        editfield_pestimation_objfunchandle  matlab.ui.control.EditField
        EstimateparametersButton        matlab.ui.control.Button
        ParameterDashboardButton        matlab.ui.control.Button
        table_pestimation               matlab.ui.control.Table
        SaveasdashboardButton           matlab.ui.control.Button
        NameEditFieldLabel              matlab.ui.control.Label
        editfield_pestimation_dashboardname  matlab.ui.control.EditField
        csvLabel_3                      matlab.ui.control.Label
        iobjectivefunctionfileshouldbeinCVQMtoolsfunctionsLabel_2  matlab.ui.control.Label
        VerifyConstraintsTab            matlab.ui.container.Tab
        editfield_verifyvp_pdashboard   matlab.ui.control.EditField
        VerifyButton                    matlab.ui.control.Button
        ParameterDashboardButton_2      matlab.ui.control.Button
        table_verifyvp                  matlab.ui.control.Table
        ViewmodelconstraintsButton      matlab.ui.control.Button
        table_verifyvp_constraints      matlab.ui.control.Table
        iModelconstraintsaresavedinCVQMtoolsfilesconstantsmatLabel  matlab.ui.control.Label
        label_appstatus                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        errorcolor=[1 0.5 0.5]; % Description
        successcolor=[0.5 0.8 0.5]; % Description
        defaultcolor=[0 0 0]; % Description
    end
    
    methods (Access = private)
        
        function saveplot(app,UIAxes,Outputfile)
            if ~(exist('C:\VQMtools\figures','file')==7)
                mkdir('C:\VQMtools\figures');
            end
            
            h = figure;
            h.Visible = 'off';
            hAxes=axes(h);
            allChildren = UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, hAxes)
            hAxes.XTick=UIAxes.XTick;
            hAxes.YTick=UIAxes.YTick;
            hAxes.XTickLabel=UIAxes.XTickLabel;
            hAxes.YTickLabel=UIAxes.YTickLabel;
            hAxes.YLabel.String = UIAxes.YLabel.String;
            hAxes.YLabel.FontSize = UIAxes.YLabel.FontSize;
            hAxes.XLabel.String = UIAxes.XLabel.String;
            hAxes.XLabel.FontSize = UIAxes.XLabel.FontSize;
            hAxes.Title.String = UIAxes.Title.String;
            hAxes.Title.FontSize = UIAxes.Title.FontSize;
            hAxes.Title.Interpreter='none';
            hAxes.XLabel.Interpreter='none';
            hAxes.YLabel.Interpreter='none';
            hAxes.XLabel.FontWeight=UIAxes.XLabel.FontWeight;
            hAxes.YLabel.FontWeight=UIAxes.YLabel.FontWeight;
            hAxes.FontWeight=UIAxes.FontWeight;
            hAxes.FontSize=UIAxes.FontSize;
            hAxes.XLim = [UIAxes.XLim(1) UIAxes.XLim(2)];
            hAxes.YLim = [UIAxes.YLim(1) UIAxes.YLim(2)];
            set(gca,'TickLabelInterpreter','none');
            saveas(h,Outputfile);
            delete(h)
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\VQMtools\');
            addpath('C:\VQMtools\functions\');
        end

        % Button pushed function: SelectprojectfileButton
        function SelectprojectfileButtonPushed(app, event)
%             [file,path]=uigetfile({'*.sbproj';'*.xml'},'Select a QSP project file');
            [file,path]=uigetfile({'*.sbproj;*.sbml;*.xml','Model file (*.sbproj,*.sbml,*.xml)';...
                '*.sbproj','SimBiology project file (*.sbproj)';...
                '*.sbml;*.xml','SBML file (*.sbml,*.xml)'},'Select a QSP project file');
            if file==0
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Please select a QSP project file';
                return;
            end
            projectfilepath=strcat(path,file);
            app.editfield_projectfile.Value=projectfilepath;
            
        end

        % Button pushed function: button_pdashboard_list
        function button_pdashboard_listPushed(app, event)
            if exist('C:\VQMtools\files\constants.mat','file')==0
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Please import a model file';
                return;
            end
            mb=msgbox('Reading parameters...');
            
            paramtable=model.readvalues();
            paramtable.isvariable=false(size(paramtable,1),1);
            paramtable=movevars(paramtable,'value','After','isvariable');            
            paramtable.lb=0.5*paramtable.value;
            paramtable.ub=2*paramtable.value;
            
            app.table_pdashboard.Data=paramtable;
            
            app.checkbox_pdashboard_isratio.Enable='on';
            app.checkbox_pdashboard_isratio.Value=false;            
            
            delete(mb);
            
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Finished loading parameters';
        
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'Load from model')
                    app.button_pdashboard_list.Enable='on';
                    app.button_pdashboard_load.Enable='off';
                    app.editfield_pdashboard_parameterfile.Enable='off';
            elseif strcmp(selectedButton.Text,'Load from file')
                    app.button_pdashboard_list.Enable='off';
                    app.button_pdashboard_load.Enable='on';
                    app.editfield_pdashboard_parameterfile.Enable='on';
            end
            
        end

        % Button pushed function: SelectParameterdashboardButton
        function SelectParameterdashboardButtonPushed(app, event)
            [file,path]=uigetfile({'*.csv'},'Select a QSP project file');
            if file==0
                return;
            end
            paramfilepath=strcat(path,file);
%                 msgbox(paramfilepath);
            app.editfield_lsa_parameterfile.Value=paramfilepath;

        end

        % Button pushed function: RunSensitivityanalysisButton
        function RunSensitivityanalysisButtonPushed(app, event)
            mb=msgbox('Running sensitivity analysis...');
            
            paramfilepath=app.editfield_lsa_parameterfile.Value;
            objfunc=app.editfield_lsa_objhandle.Value;
            
            lsaoutput=analysis.lsa('parameterdashboard',paramfilepath,'objectivefunction',str2func(objfunc));
            
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Drawing plot....';
                
            % Show only top 20 sensitive parameters
            if size(lsaoutput,1) > 20
                lsaoutput(21:end,:)=[];
            end
            
            barh(app.plot_lsa,lsaoutput.lb_sensitivity,'b');
            hold(app.plot_lsa,'on');
            barh(app.plot_lsa,lsaoutput.ub_sensitivity,'r');
            xlim(app.plot_lsa,[-100 100]);
            title(app.plot_lsa,objfunc,'Interpreter','none');
            xlabel(app.plot_lsa,'% Change from baseline');
            ylabel(app.plot_lsa,'');
            yticks(app.plot_lsa,1:size(lsaoutput,1));
            yticklabels(app.plot_lsa,lsaoutput.name);
            set(app.plot_lsa,'TickLabelInterpreter','none')
            set(app.plot_lsa,'FontSize',14,'FontWeight','bold');
            legend(app.plot_lsa,{'low','high'},'Location','southeast');
            
            delete(mb);
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Finished';
            
    
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if strcmp(app.editfield_lsa_figname.Value,'')
                return;
            end
            
            if contains(app.editfield_lsa_figname.Value,'.')
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Filename cannot have \'.\' in it';
                return;
            end

            app.label_appstatus.FontColor=app.defaultcolor;
            app.label_appstatus.Text='saving plot...';
            
            
            figpath=strcat('C:\VQMtools\figures\',app.editfield_lsa_figname.Value,'.png');
%             msgbox(figpath);
            saveplot(app,app.plot_lsa,figpath);

            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text=['Tornado plot saved to ' figpath];
            
        end

        % Button pushed function: button_pdashboard_save
        function button_pdashboard_saveButtonPushed(app, event)
            if strcmp(app.editfield_pdashboard_savename.Value,'')
                return;
            end
            
            if contains(app.editfield_pdashboard_savename.Value,'.')
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Filename cannot not have \'.\' in it';
                return;
            end
            
            app.label_appstatus.FontColor=app.defaultcolor;
            app.label_appstatus.Text='Saving parameters...';
            
            filepath=strcat('C:\VQMtools\files\',app.editfield_pdashboard_savename.Value,'.csv');
            
            % Save the range as a number , not as a ratio
            savetabledata=app.table_pdashboard.Data;
            
            % sort by isvariable and by name
            savetabledata=sortrows(savetabledata,[2 1],{'descend','ascend'});
            
            if app.checkbox_pdashboard_isratio.Value
                savetabledata.lb=savetabledata.lb.*savetabledata.value;
                savetabledata.ub=savetabledata.ub.*savetabledata.value;
            end
            
            
            writetable(savetabledata,filepath,'Delimiter',',');

            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Parameter dashboard saved';

        end

        % Button pushed function: button_pdashboard_load
        function button_pdashboard_loadButtonPushed(app, event)
            [file,path]=uigetfile({'*.csv'},'Select parameter dashboard');
            if file==0
                return;
            end
            
            mb=msgbox('Reading parameters...');            
            
            filepath=strcat(path,file);
            app.editfield_pdashboard_parameterfile.Value=filepath;
            
            ptable=readtable(filepath,...
                'Delimiter',',','ReadVariableNames',true);
            % sort by isvariable and by name
            ptable=sortrows(ptable,[2 1],{'descend','ascend'});
            
            ptable.isvariable=logical(ptable.isvariable);
            app.table_pdashboard.Data=ptable;
            
            app.checkbox_pdashboard_isratio.Enable='on';
            app.checkbox_pdashboard_isratio.Value=false;            
            
            delete(mb);
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Finished loading parameters';
            
        end

        % Value changed function: checkbox_pdashboard_isratio
        function checkbox_pdashboard_isratioValueChanged(app, event)
            showratio = app.checkbox_pdashboard_isratio.Value;
            
            baseline=app.table_pdashboard.Data.value;
            lb=app.table_pdashboard.Data.lb;
            ub=app.table_pdashboard.Data.ub;
            
            if showratio
                app.table_pdashboard.Data.lb=lb./baseline;
                app.table_pdashboard.Data.ub=ub./baseline;                
            else
                app.table_pdashboard.Data.lb=lb.*baseline;
                app.table_pdashboard.Data.ub=ub.*baseline;                
            end
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            
            if strcmp(selectedButton.Text,'Load from model')
                    app.button_sdashboard_list.Enable='on';
                    app.button_sdashboard_load.Enable='off';
                    app.editfield_sdashboard_speciesfile.Enable='off';
            elseif strcmp(selectedButton.Text,'Load from file')
                    app.button_sdashboard_list.Enable='off';
                    app.button_sdashboard_load.Enable='on';
                    app.editfield_sdashboard_speciesfile.Enable='on';
            end
            
        end

        % Button pushed function: button_sdashboard_list
        function button_sdashboard_listButtonPushed(app, event)
            if exist('C:\VQMtools\files\constants.mat','file')==0
                return;
            end
            
            mb=msgbox('Reading Species...');
            
            [~,speciestable]=model.readvalues();
            speciestable.lb=0.5*speciestable.value;
            speciestable.ub=2*speciestable.value;
            
            app.table_sdashboard.Data=speciestable;
            
            delete(mb);
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Finished loading species';

        end

        % Button pushed function: button_sdashboard_load
        function button_sdashboard_loadButtonPushed(app, event)
            [file,path]=uigetfile({'*.csv'},'Select Species dashboard');
            if file==0
                return;
            end
            
            mb=msgbox('Reading Species...');
                        
            filepath=strcat(path,file);
            app.editfield_sdashboard_speciesfile.Value=filepath;
            
            stable=readtable(filepath,...
                'Delimiter',',','ReadVariableNames',true);
                        
            app.table_sdashboard.Data=stable;
            
            delete(mb);
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='File read successfully';           
        end

        % Button pushed function: button_sdashboard_save
        function button_sdashboard_saveButtonPushed(app, event)
            if strcmp(app.editfield_sdashboard_savename.Value,'')
                return;
            end
            
            if contains(app.editfield_sdashboard_savename.Value,'.')
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Filename cannot not have \'.\' in it';
                return;
            end
            
            app.label_appstatus.FontColor=app.defaultcolor;
            app.label_appstatus.Text='Creating dashboard...';
            
            filepath=strcat('C:\VQMtools\files\',app.editfield_sdashboard_savename.Value,'.csv');
            
            % Save the range as a number , not as a ratio
            savetabledata=app.table_sdashboard.Data;            
            writetable(savetabledata,filepath,'Delimiter',',');

            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Species dashboard saved';            
        end

        % Button pushed function: ToggleselectionButton
        function ToggleselectionButtonPushed(app, event)
            if isempty(app.editfield_pdashboard_startpattern.Value)
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Edit field cannot be empty';            
                return;
            end
            pattern=app.editfield_pdashboard_startpattern.Value;
            
            togglerows=cellfun(@(x) startsWith(x,pattern),app.table_pdashboard.Data.name);
            app.table_pdashboard.Data.isvariable(togglerows)=~app.table_pdashboard.Data.isvariable(togglerows);
            
            app.editfield_pdashboard_startpattern.Value='';
        end

        % Button pushed function: ResetselectionButton
        function ResetselectionButtonPushed(app, event)
            
            numrows=size(app.table_pdashboard.Data,1);
            if numrows==0
                return;
            end
            
            app.table_pdashboard.Data.isvariable=false(numrows,1);
            
        end

        % Button pushed function: ParameterDashboardButton
        function ParameterDashboardButtonPushed(app, event)
            [file,path]=uigetfile({'*.csv'},'Select parameter dashboard');
            if file==0
                return;
            end

            filepath=strcat(path,file);
            
            app.editfield_pestimation_pdashboard.Value=filepath;
            
            
        end

        % Callback function
        function ConstraintsfileButtonPushed(app, event)
            [file,path]=uigetfile({'*.mat'},'Select Constraints file');
            if file==0
                return;
            end

            filepath=strcat(path,file);
            
            app.editfield_pestimation_constraintsfile.Value=filepath;

        end

        % Button pushed function: EstimateparametersButton
        function EstimateparametersButtonPushed(app, event)
            app.label_appstatus.FontColor=app.defaultcolor;
            app.label_appstatus.Text='Running parameter estimation..';

            pdashboard=app.editfield_pestimation_pdashboard.Value;
            objfunc=app.editfield_pestimation_objfunchandle.Value;
            
            finalptable=analysis.pestimation('parameterdashboard',pdashboard,...
                'objectivefunction',str2func(objfunc));
            
            finalptable.isvariable=logical(finalptable.isvariable);
            app.table_pestimation.Data=finalptable;
            
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Finished estimating parameters!';

        end

        % Button pushed function: SaveasdashboardButton
        function SaveasdashboardButtonPushed(app, event)
            paramtable=table();
            paramtable.name=app.table_pestimation.Data.name;
            paramtable.isvariable=app.table_pestimation.Data.isvariable;
            paramtable.value=app.table_pestimation.Data.estimatedvalue;
            paramtable.lb=0.5*paramtable.value;
            paramtable.ub=2*paramtable.value;
            
            filepath=['C:\VQMtools\files\' app.editfield_pestimation_dashboardname.Value '.csv'];
            
            writetable(paramtable,filepath,'Delimiter',',');

            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Saved new parameter dashboard!';

        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            
            app.label_appstatus.FontColor=app.defaultcolor;
            app.label_appstatus.Text='Verifying Virtual Patient...';

            pdashboardpath=app.editfield_verifyvp_pdashboard.Value;
            
            constraintverification=model.verifyvp(pdashboardpath);
            
            if isempty(constraintverification)
                app.label_appstatus.FontColor=app.errorcolor;
                app.label_appstatus.Text='Constraints are not defined for the model';
            end
            
            constraintverification.status=logical(constraintverification.status);
            app.table_verifyvp.Data=constraintverification;
            
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Verification finished!';
            
        end

        % Button pushed function: ParameterDashboardButton_2
        function ParameterDashboardButton_2Pushed(app, event)
            [file,path]=uigetfile({'*.csv'},'Select parameter dashboard');
            if file==0
                return;
            end

            app.editfield_verifyvp_pdashboard.Value=strcat(path,file);

        end

        % Button pushed function: ViewmodelconstraintsButton
        function ViewmodelconstraintsButtonPushed(app, event)
           modelconstraints=model.listelements('constraints');
           if isempty(modelconstraints)
               app.label_appstatus.FontColor=app.errorcolor;
               app.label_appstatus.Text='There are no constraints defined';
               return;
           end
           app.table_verifyvp_constraints.Data=modelconstraints;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            mb=msgbox('Importing model...','Status','help');
            projectfilepath=app.editfield_projectfile.Value;
            
            is_replacedashboards=app.cb_replacedashboards.Value;
            projectname=app.editfield_projectname.Value;
            
            [paramlist,specieslist]=model.read('filepath',projectfilepath,'replacedashboards',is_replacedashboards,...
                'name',projectname);
            
%             paramlist=model.listelements('parameters');
%             paramlist=sortrows(paramlist); % Sort names alphabetically
%             
%             specieslist=model.listelements('species');
%             specieslist=sortrows(specieslist); % Sort names alphabetically
            delete(mb);
            mb=msgbox('Loading parameters and species...','Status','help');
            
            % Split list into 3 columns and add to uitable
            paramtablerows=ceil(length(paramlist)/3);
            rowstoadd=paramtablerows*3-length(paramlist);
            paramlist(end+1:end+rowstoadd)={''};
            paramlist=reshape(paramlist,paramtablerows,3);
            app.table_parameters.Data=paramlist;
            
            % Split list into 3 columns and add to uitable
            speciestablerows=ceil(length(specieslist)/3);
            rowstoadd=speciestablerows*3-length(specieslist);
            specieslist(end+1:end+rowstoadd)={''};
            specieslist=reshape(specieslist,speciestablerows,3);
            app.table_species.Data=specieslist;
            
            delete(mb);
            app.label_appstatus.FontColor=app.successcolor;
            app.label_appstatus.Text='Finished reading successfully';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VantageQSPModellingVQMToolsUIFigure = uifigure;
            app.VantageQSPModellingVQMToolsUIFigure.Name = 'Vantage QSP Modelling(VQM) Tools';
            app.TabGroup = uitabgroup(app.VantageQSPModellingVQMToolsUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.SelectprojectfileButton = uibutton(app.HomeTab, 'push');
            app.SelectprojectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectprojectfileButtonPushed, true);
            app.SelectprojectfileButton.Text = 'Select project file';
            app.editfield_projectfile = uieditfield(app.HomeTab, 'text');
            app.table_parameters = uitable(app.HomeTab);
            app.table_parameters.ColumnName = {''};
            app.table_parameters.RowName = {};
            app.table_species = uitable(app.HomeTab);
            app.table_species.ColumnName = {''};
            app.table_species.RowName = {};
            app.ParametersLabel = uilabel(app.HomeTab);
            app.ParametersLabel.Text = 'Parameters';
            app.SpeciesLabel = uilabel(app.HomeTab);
            app.SpeciesLabel.Text = 'Species';
            app.sbprojxmlfileLabel = uilabel(app.HomeTab);
            app.sbprojxmlfileLabel.Text = '.sbproj, .xml file';
            app.cb_replacedashboards = uicheckbox(app.HomeTab);
            app.cb_replacedashboards.Text = 'Replace dashboards';
            app.cb_replacedashboards.Value = true;
            app.ProjectnameEditFieldLabel = uilabel(app.HomeTab);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.editfield_projectname = uieditfield(app.HomeTab, 'text');
            app.editfield_projectname.Value = 'QSP Project';
            app.ImportButton = uibutton(app.HomeTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ParameterdashboardTab = uitab(app.TabGroup);
            app.ParameterdashboardTab.Title = 'Parameter dashboard';
            app.table_pdashboard = uitable(app.ParameterdashboardTab);
            app.table_pdashboard.ColumnName = {'Name'; 'isVariable*'; 'Value*'; 'Minimum*'; 'Maximum*'};
            app.table_pdashboard.RowName = {};
            app.table_pdashboard.ColumnEditable = [false true true true true];
            app.button_pdashboard_list = uibutton(app.ParameterdashboardTab, 'push');
            app.button_pdashboard_list.ButtonPushedFcn = createCallbackFcn(app, @button_pdashboard_listPushed, true);
            app.button_pdashboard_list.Text = 'List Model Parameters';
            app.button_pdashboard_save = uibutton(app.ParameterdashboardTab, 'push');
            app.button_pdashboard_save.ButtonPushedFcn = createCallbackFcn(app, @button_pdashboard_saveButtonPushed, true);
            app.button_pdashboard_save.Text = 'Save dashboard';
            app.DashboardnameEditFieldLabel = uilabel(app.ParameterdashboardTab);
            app.DashboardnameEditFieldLabel.Text = 'Dashboard name';
            app.editfield_pdashboard_savename = uieditfield(app.ParameterdashboardTab, 'text');
            app.button_pdashboard_load = uibutton(app.ParameterdashboardTab, 'push');
            app.button_pdashboard_load.ButtonPushedFcn = createCallbackFcn(app, @button_pdashboard_loadButtonPushed, true);
            app.button_pdashboard_load.Enable = 'off';
            app.button_pdashboard_load.Text = 'Load file';
            app.editfield_pdashboard_parameterfile = uieditfield(app.ParameterdashboardTab, 'text');
            app.editfield_pdashboard_parameterfile.Enable = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.ParameterdashboardTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.radio_pdashboard_frommodel = uiradiobutton(app.ButtonGroup_2);
            app.radio_pdashboard_frommodel.Text = 'Load from model';
            app.radio_pdashboard_frommodel.Value = true;
            app.radio_pdashboard_fromdashboard = uiradiobutton(app.ButtonGroup_2);
            app.radio_pdashboard_fromdashboard.Text = 'Load from file';
            app.iDashboardwillbesavedinCVQMtoolsfilesLabel_2 = uilabel(app.ParameterdashboardTab);
            app.iDashboardwillbesavedinCVQMtoolsfilesLabel_2.Text = 'i: Dashboard will be saved in C:\VQMtools\files\';
            app.checkbox_pdashboard_isratio = uicheckbox(app.ParameterdashboardTab);
            app.checkbox_pdashboard_isratio.ValueChangedFcn = createCallbackFcn(app, @checkbox_pdashboard_isratioValueChanged, true);
            app.checkbox_pdashboard_isratio.Enable = 'off';
            app.checkbox_pdashboard_isratio.Text = 'Show ratio for Minimum and Maximum';
            app.csvLabel = uilabel(app.ParameterdashboardTab);
            app.csvLabel.Text = '.csv';
            app.StartingwithEditFieldLabel = uilabel(app.ParameterdashboardTab);
            app.StartingwithEditFieldLabel.Text = 'Starting with';
            app.editfield_pdashboard_startpattern = uieditfield(app.ParameterdashboardTab, 'text');
            app.ToggleselectionButton = uibutton(app.ParameterdashboardTab, 'push');
            app.ToggleselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleselectionButtonPushed, true);
            app.ToggleselectionButton.Text = 'Toggle selection';
            app.ResetselectionButton = uibutton(app.ParameterdashboardTab, 'push');
            app.ResetselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetselectionButtonPushed, true);
            app.ResetselectionButton.Text = 'Reset selection';
            app.csvLabel_4 = uilabel(app.ParameterdashboardTab);
            app.csvLabel_4.Text = '.csv';
            app.iEditableLabel_2 = uilabel(app.ParameterdashboardTab);
            app.iEditableLabel_2.Text = 'i: *-Editable';
            app.SpeciesdashboardTab = uitab(app.TabGroup);
            app.SpeciesdashboardTab.Title = 'Species dashboard';
            app.ButtonGroup_3 = uibuttongroup(app.SpeciesdashboardTab);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.LoadfrommodelButton = uiradiobutton(app.ButtonGroup_3);
            app.LoadfrommodelButton.Text = 'Load from model';
            app.LoadfrommodelButton.Value = true;
            app.LoadfromfileButton = uiradiobutton(app.ButtonGroup_3);
            app.LoadfromfileButton.Text = 'Load from file';
            app.button_sdashboard_list = uibutton(app.SpeciesdashboardTab, 'push');
            app.button_sdashboard_list.ButtonPushedFcn = createCallbackFcn(app, @button_sdashboard_listButtonPushed, true);
            app.button_sdashboard_list.Text = 'List Model Species';
            app.table_sdashboard = uitable(app.SpeciesdashboardTab);
            app.table_sdashboard.ColumnName = {'Species'; 'Value*'; 'Minimum*'; 'Maximum*'};
            app.table_sdashboard.RowName = {};
            app.table_sdashboard.ColumnEditable = [false true true true];
            app.button_sdashboard_save = uibutton(app.SpeciesdashboardTab, 'push');
            app.button_sdashboard_save.ButtonPushedFcn = createCallbackFcn(app, @button_sdashboard_saveButtonPushed, true);
            app.button_sdashboard_save.Text = 'Save dashboard';
            app.DashboardnameEditFieldLabel_2 = uilabel(app.SpeciesdashboardTab);
            app.DashboardnameEditFieldLabel_2.Text = 'Dashboard name';
            app.editfield_sdashboard_savename = uieditfield(app.SpeciesdashboardTab, 'text');
            app.button_sdashboard_load = uibutton(app.SpeciesdashboardTab, 'push');
            app.button_sdashboard_load.ButtonPushedFcn = createCallbackFcn(app, @button_sdashboard_loadButtonPushed, true);
            app.button_sdashboard_load.Enable = 'off';
            app.button_sdashboard_load.Text = 'Load file';
            app.editfield_sdashboard_speciesfile = uieditfield(app.SpeciesdashboardTab, 'text');
            app.editfield_sdashboard_speciesfile.Enable = 'off';
            app.iDashboardwillbesavedinCVQMtoolsfilesLabel = uilabel(app.SpeciesdashboardTab);
            app.iDashboardwillbesavedinCVQMtoolsfilesLabel.Text = 'i: Dashboard will be saved in C:\VQMtools\files\';
            app.csvLabel_2 = uilabel(app.SpeciesdashboardTab);
            app.csvLabel_2.Text = '.csv';
            app.iEditableLabel = uilabel(app.SpeciesdashboardTab);
            app.iEditableLabel.Text = 'i:  * - Editable';
            app.LocalSenstivityAnalysisTab = uitab(app.TabGroup);
            app.LocalSenstivityAnalysisTab.Title = 'Local Senstivity Analysis';
            app.RunSensitivityanalysisButton = uibutton(app.LocalSenstivityAnalysisTab, 'push');
            app.RunSensitivityanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunSensitivityanalysisButtonPushed, true);
            app.RunSensitivityanalysisButton.Text = 'Run Sensitivity analysis';
            app.plot_lsa = uiaxes(app.LocalSenstivityAnalysisTab);
            title(app.plot_lsa, 'Tornado plot')
            app.editfield_lsa_parameterfile = uieditfield(app.LocalSenstivityAnalysisTab, 'text');
            app.SelectParameterdashboardButton = uibutton(app.LocalSenstivityAnalysisTab, 'push');
            app.SelectParameterdashboardButton.ButtonPushedFcn = createCallbackFcn(app, @SelectParameterdashboardButtonPushed, true);
            app.SelectParameterdashboardButton.Text = 'Select Parameter dashboard';
            app.editfield_lsa_objhandle = uieditfield(app.LocalSenstivityAnalysisTab, 'text');
            app.editfield_lsa_objhandle.Tooltip = {'@<function name>'};
            app.editfield_lsa_figname = uieditfield(app.LocalSenstivityAnalysisTab, 'text');
            app.SaveImageButton = uibutton(app.LocalSenstivityAnalysisTab, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ObjectivefunctionhandleLabel = uilabel(app.LocalSenstivityAnalysisTab);
            app.ObjectivefunctionhandleLabel.Text = 'Objective function handle';
            app.iobjectivefunctionfileshouldbeinCVQMtoolsfunctionsLabel = uilabel(app.LocalSenstivityAnalysisTab);
            app.iobjectivefunctionfileshouldbeinCVQMtoolsfunctionsLabel.Text = 'i: objective function file should be in C:\VQMtools\functions\';
            app.ifigurewillbesavedinCVQMtoolsfiguresLabel = uilabel(app.LocalSenstivityAnalysisTab);
            app.ifigurewillbesavedinCVQMtoolsfiguresLabel.Text = 'i: figure will be saved in C:\VQMtools\figures\ ';
            app.pngLabel = uilabel(app.LocalSenstivityAnalysisTab);
            app.pngLabel.Text = '.png';
            app.Maximumof20parametersaredisplayedLabel = uilabel(app.LocalSenstivityAnalysisTab);
            app.Maximumof20parametersaredisplayedLabel.Text = {'Maximum of 20'; 'parameters are'; 'displayed'};
            app.iLabel_5 = uilabel(app.LocalSenstivityAnalysisTab);
            app.iLabel_5.Text = 'i:';
            app.ParameterEstimationTab = uitab(app.TabGroup);
            app.ParameterEstimationTab.Title = 'Parameter Estimation';
            app.editfield_pestimation_pdashboard = uieditfield(app.ParameterEstimationTab, 'text');
            app.ObjectivefunctionEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.ObjectivefunctionEditFieldLabel.Text = 'Objective function';
            app.editfield_pestimation_objfunchandle = uieditfield(app.ParameterEstimationTab, 'text');
            app.EstimateparametersButton = uibutton(app.ParameterEstimationTab, 'push');
            app.EstimateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateparametersButtonPushed, true);
            app.EstimateparametersButton.Text = 'Estimate parameters';
            app.ParameterDashboardButton = uibutton(app.ParameterEstimationTab, 'push');
            app.ParameterDashboardButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterDashboardButtonPushed, true);
            app.ParameterDashboardButton.Text = 'Parameter Dashboard';
            app.table_pestimation = uitable(app.ParameterEstimationTab);
            app.table_pestimation.ColumnName = {'Name'; 'isEstimated'; 'Estimated Value'; 'Original Value'};
            app.table_pestimation.RowName = {};
            app.table_pestimation.ColumnEditable = false;
            app.SaveasdashboardButton = uibutton(app.ParameterEstimationTab, 'push');
            app.SaveasdashboardButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasdashboardButtonPushed, true);
            app.SaveasdashboardButton.Text = 'Save as dashboard';
            app.NameEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.editfield_pestimation_dashboardname = uieditfield(app.ParameterEstimationTab, 'text');
            app.editfield_pestimation_dashboardname.Value = 'estimatedparameterdashboard';
            app.csvLabel_3 = uilabel(app.ParameterEstimationTab);
            app.csvLabel_3.Text = '.csv';
            app.iobjectivefunctionfileshouldbeinCVQMtoolsfunctionsLabel_2 = uilabel(app.ParameterEstimationTab);
            app.iobjectivefunctionfileshouldbeinCVQMtoolsfunctionsLabel_2.Text = 'i: objective function file should be in C:\VQMtools\functions\';
            app.VerifyConstraintsTab = uitab(app.TabGroup);
            app.VerifyConstraintsTab.Title = 'Verify Constraints';
            app.editfield_verifyvp_pdashboard = uieditfield(app.VerifyConstraintsTab, 'text');
            app.VerifyButton = uibutton(app.VerifyConstraintsTab, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.ParameterDashboardButton_2 = uibutton(app.VerifyConstraintsTab, 'push');
            app.ParameterDashboardButton_2.ButtonPushedFcn = createCallbackFcn(app, @ParameterDashboardButton_2Pushed, true);
            app.ParameterDashboardButton_2.Text = 'Parameter Dashboard';
            app.table_verifyvp = uitable(app.VerifyConstraintsTab);
            app.table_verifyvp.ColumnName = {'Constraint Name'; 'Verification Status'};
            app.table_verifyvp.RowName = {};
            app.ViewmodelconstraintsButton = uibutton(app.VerifyConstraintsTab, 'push');
            app.ViewmodelconstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewmodelconstraintsButtonPushed, true);
            app.ViewmodelconstraintsButton.Text = 'View model constraints';
            app.table_verifyvp_constraints = uitable(app.VerifyConstraintsTab);
            app.table_verifyvp_constraints.ColumnName = {'Sno'; 'name'; 'constraint'};
            app.table_verifyvp_constraints.RowName = {};
            app.iModelconstraintsaresavedinCVQMtoolsfilesconstantsmatLabel = uilabel(app.VerifyConstraintsTab);
            app.iModelconstraintsaresavedinCVQMtoolsfilesconstantsmatLabel.Text = 'i: Model constraints are saved in C:\VQMtools\files\constants.mat';
            app.label_appstatus = uilabel(app.VantageQSPModellingVQMToolsUIFigure);
            app.label_appstatus.Text = 'Hey there, thanks for using VQMTools';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VantageQSPModellingVQMToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VantageQSPModellingVQMToolsUIFigure)
        end
    end
end